classdef ROIGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DICOMInfoUIFigure        matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UITable                  matlab.ui.control.Table
        ImageSelectSpinnerLabel  matlab.ui.control.Label
        ImageSelectSpinner       matlab.ui.control.Spinner
        OriginalFrontalImageButtonGroup  matlab.ui.container.ButtonGroup
        OriginalButton           matlab.ui.control.ToggleButton
        EnhancedButton           matlab.ui.control.ToggleButton
        LateralButton            matlab.ui.control.ToggleButton
    end


    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;            
            
            % Plot modified data
            x2 = t.Age;
            y2 = t.BloodPressure(:,2);
            plot(app.UIAxes2,x2,y2,'-o');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('patients.xls');
            vars = {'Age','Systolic','Diastolic','SelfAssessedHealthStatus','Smoker'};
            
            % Select a subset of the table array
            t = t(1:20,vars);
            
            % Sort the data by age
            t = sortrows(t,'Age');
            
            % Combine Systolic and Diastolic into one variable
            t.BloodPressure = [t.Systolic t.Diastolic];
            t.Systolic = [];
            t.Diastolic = [];
            
            % Convert SelfAssessedHealthStatus to categorical
            cats = categorical(t.SelfAssessedHealthStatus,{'Poor','Fair','Good','Excellent'});
            t.SelfAssessedHealthStatus = cats;
            
            % Rearrange columns
            t = t(:,[1 4 3 2]);
            
            % Add data to the Table UI Component
            app.UITable.Data = t;
            
            % Plot the original data
            x1 = app.UITable.Data.Age;
            y1 = app.UITable.Data.BloodPressure(:,2);
            plot(app.UIAxes,x1,y1,'o-');
            
            % Plot the data
            updateplot(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DICOMInfoUIFigure = uifigure('Visible', 'off');
            app.DICOMInfoUIFigure.Name = 'DICOMInfo';
            app.UIAxes = uiaxes(app.DICOMInfoUIFigure);
            title(app.UIAxes, 'Original Data')
            xlabel(app.UIAxes, 'Age')
            ylabel(app.UIAxes, 'Diastolic Blood Pressure')
            app.UITable = uitable(app.DICOMInfoUIFigure);
            app.UITable.ColumnName = {'Field Name'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false];
            app.UITable.ColumnEditable = [false false];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ImageSelectSpinnerLabel = uilabel(app.DICOMInfoUIFigure);
            app.ImageSelectSpinnerLabel.Text = 'Image Select';
            app.ImageSelectSpinner = uispinner(app.DICOMInfoUIFigure);
            app.OriginalFrontalImageButtonGroup = uibuttongroup(app.DICOMInfoUIFigure);
            app.OriginalFrontalImageButtonGroup.Title = 'Original Frontal Image?';
            app.OriginalButton = uitogglebutton(app.OriginalFrontalImageButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.EnhancedButton = uitogglebutton(app.OriginalFrontalImageButtonGroup);
            app.EnhancedButton.Text = 'Enhanced';
            app.LateralButton = uitogglebutton(app.OriginalFrontalImageButtonGroup);
            app.LateralButton.Text = 'Lateral';
            % Show the figure after all components are created
            app.DICOMInfoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DICOMInfoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DICOMInfoUIFigure)
        end
    end
end